classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CorTab                        matlab.ui.container.Tab
        AplicarButton                 matlab.ui.control.Button
        BetaLabel                     matlab.ui.control.Label
        Beta                          matlab.ui.control.Spinner
        Label_2                       matlab.ui.control.Label
        rac                           matlab.ui.control.Slider
        RCheckBox                     matlab.ui.control.CheckBox
        GCheckBox                     matlab.ui.control.CheckBox
        BCheckBox                     matlab.ui.control.CheckBox
        RminEditFieldLabel            matlab.ui.control.Label
        RminEditField                 matlab.ui.control.NumericEditField
        RmaxEditFieldLabel            matlab.ui.control.Label
        RmaxEditField                 matlab.ui.control.NumericEditField
        GminEditFieldLabel            matlab.ui.control.Label
        GminEditField                 matlab.ui.control.NumericEditField
        GmaxEditFieldLabel            matlab.ui.control.Label
        GmaxEditField                 matlab.ui.control.NumericEditField
        BminEditFieldLabel            matlab.ui.control.Label
        BminEditField                 matlab.ui.control.NumericEditField
        BmaxEditFieldLabel            matlab.ui.control.Label
        BmaxEditField                 matlab.ui.control.NumericEditField
        PBTab                         matlab.ui.container.Tab
        ValordeFronteiraSpinnerLabel  matlab.ui.control.Label
        ValordeFronteiraSpinner       matlab.ui.control.Spinner
        PretoeBrancoButton            matlab.ui.control.Button
        NitidezTab                    matlab.ui.container.Tab
        BlurButton                    matlab.ui.control.Button
        EdgesButton                   matlab.ui.control.Button
        SharpenButton                 matlab.ui.control.Button
        DimKernelSpinnerLabel         matlab.ui.control.Label
        DimKernelSpinner              matlab.ui.control.Spinner
        SigmaEditFieldLabel           matlab.ui.control.Label
        SigmaEditField                matlab.ui.control.NumericEditField
        AlfaEditFieldLabel            matlab.ui.control.Label
        AlfaEditField                 matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        FicheiroPanel                 matlab.ui.container.Panel
        SelecionarFicheiroButton      matlab.ui.control.Button
        ReiniciarButton               matlab.ui.control.Button
        GuardarButton                 matlab.ui.control.Button
        NomeEditFieldLabel            matlab.ui.control.Label
        NomeEditField                 matlab.ui.control.EditField
        DesfazerButton                matlab.ui.control.Button
    end


    properties (Access = public)
        image_chosen % Description
        image_edit % Description
    end

    properties (Access = private)
        name_file = 'imagem' % Description
        image_undo % Description
        kernel_size = 3 % Description
        sigma = 1 % Description
        alpha = 1 % Description
        tau = 127 % Description
        beta = 0 % Description
        racio = 1% Description
        R = 0 % Description
        G = 0
        B = 0
        a = 0
        b = 255
        c = 0
        d = 255
        e = 0
        f = 255
    end


    methods (Access = private)

        % Button pushed function: SelecionarFicheiroButton
        function SelecionarFicheiroButtonPushed(app, event)
            app.image_chosen = imread(uigetfile('*.jpg'));
            app.image_edit = app.image_chosen;
            imshow(app.image_chosen, 'Parent', app.UIAxes)
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            app.image_edit = app.image_chosen;
            imshow(app.image_chosen, 'Parent', app.UIAxes)
        end

        % Value changed function: NomeEditField
        function NomeEditFieldValueChanged(app, event)
            app.name_file = app.NomeEditField.Value;
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            imwrite(app.image_edit, sprintf('%s.jpg', app.name_file), 'jpg')
        end

        % Button pushed function: DesfazerButton
        function DesfazerButtonPushed(app, event)
           app.image_edit = app.image_undo;
           imshow(app.image_edit, 'Parent', app.UIAxes)
        end

        % Button pushed function: BlurButton
        function BlurButtonPushed(app, event)
            app.image_undo = app.image_edit;
            app.image_edit = convulotion(app.image_edit, build_gaussian_kernel(app.sigma,app.kernel_size));
            imshow(app.image_edit, 'Parent', app.UIAxes)
        end

        % Value changed function: DimKernelSpinner
        function DimKernelSpinnerValueChanged(app, event)
            app.kernel_size = app.DimKernelSpinner.Value;
            
        end

        % Value changed function: SigmaEditField
        function SigmaEditFieldValueChanged(app, event)
            app.sigma = app.SigmaEditField.Value;
            
        end

        % Value changed function: AlfaEditField
        function AlfaEditFieldValueChanged(app, event)
            app.alpha = app.AlfaEditField.Value;
            
        end

        % Button pushed function: EdgesButton
        function EdgesButtonPushed(app, event)
            app.image_undo = app.image_edit;
            app.image_edit = convulotion(app.image_edit, build_edge_kernel(app.alpha,app.kernel_size));
            imshow(app.image_edit, 'Parent', app.UIAxes)
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            app.image_undo = app.image_edit;
            app.image_edit = convulotion(app.image_edit, build_sharp_kernel(app.alpha,app.kernel_size));
            imshow(app.image_edit, 'Parent', app.UIAxes)
        end

        % Value changed function: ValordeFronteiraSpinner
        function ValordeFronteiraSpinnerValueChanged(app, event)
            app.tau = app.ValordeFronteiraSpinner.Value;
            
        end

        % Value changed function: Beta
        function BetaValueChanged(app, event)
            app.beta = app.Beta.Value;
            
        end

        % Value changed function: rac
        function racValueChanged(app, event)
            app.racio = app.rac.Value;
            
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            app.image_undo = app.image_edit;
            app.image_edit = toning_image(app.R, app.G, app.B, app.a,app.b,app.c,app.d,app.e,app.f,app.image_edit, app.racio, app.beta);
            imshow(app.image_edit, 'Parent', app.UIAxes)
        end

        % Button pushed function: PretoeBrancoButton
        function PretoeBrancoButtonPushed(app, event)
            app.image_undo = app.image_edit;
            app.image_edit = toning_correction(app.image_edit, app.tau);
            imshow(app.image_edit, 'Parent', app.UIAxes)
        end

        % Value changed function: RCheckBox
        function RCheckBoxValueChanged(app, event)
            app.R = double(app.RCheckBox.Value);
            
        end

        % Value changed function: GCheckBox
        function GCheckBoxValueChanged(app, event)
            app.G = double(app.GCheckBox.Value);
            
        end

        % Value changed function: BCheckBox
        function BCheckBoxValueChanged(app, event)
            app.B = double(app.BCheckBox.Value);
            
        end

        % Value changed function: RminEditField
        function RminEditFieldValueChanged(app, event)
            app.a = app.RminEditField.Value;
            
        end

        % Value changed function: RmaxEditField
        function RmaxEditFieldValueChanged(app, event)
            app.b = app.RmaxEditField.Value;
            
        end

        % Value changed function: GminEditField
        function GminEditFieldValueChanged(app, event)
            app.c = app.GminEditField.Value;
            
        end

        % Value changed function: GmaxEditField
        function GmaxEditFieldValueChanged(app, event)
            app.d = app.GmaxEditField.Value;
            
        end

        % Value changed function: BminEditField
        function BminEditFieldValueChanged(app, event)
            app.e = app.BminEditField.Value;
            
        end

        % Value changed function: BmaxEditField
        function BmaxEditFieldValueChanged(app, event)
            app.f = app.BmaxEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CorTab = uitab(app.TabGroup);
            app.CorTab.Title = 'Cor       ';
            app.AplicarButton = uibutton(app.CorTab, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.BetaLabel = uilabel(app.CorTab);
            app.BetaLabel.Text = 'Beta';
            app.Beta = uispinner(app.CorTab);
            app.Beta.Limits = [-255 255];
            app.Beta.ValueChangedFcn = createCallbackFcn(app, @BetaValueChanged, true);
            app.Label_2 = uilabel(app.CorTab);
            app.Label_2.Text = '%';
            app.rac = uislider(app.CorTab);
            app.rac.Limits = [0 1];
            app.rac.ValueChangedFcn = createCallbackFcn(app, @racValueChanged, true);
            app.rac.Value = 1;
            app.RCheckBox = uicheckbox(app.CorTab);
            app.RCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.RCheckBox.Text = 'R';
            app.GCheckBox = uicheckbox(app.CorTab);
            app.GCheckBox.ValueChangedFcn = createCallbackFcn(app, @GCheckBoxValueChanged, true);
            app.GCheckBox.Text = 'G';
            app.BCheckBox = uicheckbox(app.CorTab);
            app.BCheckBox.ValueChangedFcn = createCallbackFcn(app, @BCheckBoxValueChanged, true);
            app.BCheckBox.Text = 'B';
            app.RminEditFieldLabel = uilabel(app.CorTab);
            app.RminEditFieldLabel.Text = 'Rmin';
            app.RminEditField = uieditfield(app.CorTab, 'numeric');
            app.RminEditField.Limits = [0 255];
            app.RminEditField.ValueChangedFcn = createCallbackFcn(app, @RminEditFieldValueChanged, true);
            app.RmaxEditFieldLabel = uilabel(app.CorTab);
            app.RmaxEditFieldLabel.Text = 'Rmax';
            app.RmaxEditField = uieditfield(app.CorTab, 'numeric');
            app.RmaxEditField.Limits = [0 255];
            app.RmaxEditField.ValueChangedFcn = createCallbackFcn(app, @RmaxEditFieldValueChanged, true);
            app.RmaxEditField.Value = 255;
            app.GminEditFieldLabel = uilabel(app.CorTab);
            app.GminEditFieldLabel.Text = 'Gmin';
            app.GminEditField = uieditfield(app.CorTab, 'numeric');
            app.GminEditField.Limits = [0 255];
            app.GminEditField.ValueChangedFcn = createCallbackFcn(app, @GminEditFieldValueChanged, true);
            app.GmaxEditFieldLabel = uilabel(app.CorTab);
            app.GmaxEditFieldLabel.Text = 'Gmax';
            app.GmaxEditField = uieditfield(app.CorTab, 'numeric');
            app.GmaxEditField.Limits = [0 255];
            app.GmaxEditField.ValueChangedFcn = createCallbackFcn(app, @GmaxEditFieldValueChanged, true);
            app.GmaxEditField.Value = 255;
            app.BminEditFieldLabel = uilabel(app.CorTab);
            app.BminEditFieldLabel.Text = 'Bmin';
            app.BminEditField = uieditfield(app.CorTab, 'numeric');
            app.BminEditField.Limits = [0 255];
            app.BminEditField.ValueChangedFcn = createCallbackFcn(app, @BminEditFieldValueChanged, true);
            app.BmaxEditFieldLabel = uilabel(app.CorTab);
            app.BmaxEditFieldLabel.Text = 'Bmax';
            app.BmaxEditField = uieditfield(app.CorTab, 'numeric');
            app.BmaxEditField.Limits = [0 255];
            app.BmaxEditField.ValueChangedFcn = createCallbackFcn(app, @BmaxEditFieldValueChanged, true);
            app.BmaxEditField.Value = 255;
            app.PBTab = uitab(app.TabGroup);
            app.PBTab.Title = 'PB      ';
            app.ValordeFronteiraSpinnerLabel = uilabel(app.PBTab);
            app.ValordeFronteiraSpinnerLabel.Text = 'Valor de Fronteira';
            app.ValordeFronteiraSpinner = uispinner(app.PBTab);
            app.ValordeFronteiraSpinner.Limits = [0 255];
            app.ValordeFronteiraSpinner.ValueChangedFcn = createCallbackFcn(app, @ValordeFronteiraSpinnerValueChanged, true);
            app.ValordeFronteiraSpinner.Value = 127;
            app.PretoeBrancoButton = uibutton(app.PBTab, 'push');
            app.PretoeBrancoButton.ButtonPushedFcn = createCallbackFcn(app, @PretoeBrancoButtonPushed, true);
            app.PretoeBrancoButton.Text = 'Preto e Branco';
            app.NitidezTab = uitab(app.TabGroup);
            app.NitidezTab.Title = 'Nitidez    ';
            app.BlurButton = uibutton(app.NitidezTab, 'push');
            app.BlurButton.ButtonPushedFcn = createCallbackFcn(app, @BlurButtonPushed, true);
            app.BlurButton.Text = 'Blur';
            app.EdgesButton = uibutton(app.NitidezTab, 'push');
            app.EdgesButton.ButtonPushedFcn = createCallbackFcn(app, @EdgesButtonPushed, true);
            app.EdgesButton.Text = 'Edges';
            app.SharpenButton = uibutton(app.NitidezTab, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Sharpen';
            app.DimKernelSpinnerLabel = uilabel(app.NitidezTab);
            app.DimKernelSpinnerLabel.Text = 'Dim. Kernel';
            app.DimKernelSpinner = uispinner(app.NitidezTab);
            app.DimKernelSpinner.Step = 2;
            app.DimKernelSpinner.Limits = [3 Inf];
            app.DimKernelSpinner.ValueChangedFcn = createCallbackFcn(app, @DimKernelSpinnerValueChanged, true);
            app.DimKernelSpinner.Value = 3;
            app.SigmaEditFieldLabel = uilabel(app.NitidezTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.NitidezTab, 'numeric');
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaEditFieldValueChanged, true);
            app.SigmaEditField.Value = 1;
            app.AlfaEditFieldLabel = uilabel(app.NitidezTab);
            app.AlfaEditFieldLabel.Text = 'Alfa';
            app.AlfaEditField = uieditfield(app.NitidezTab, 'numeric');
            app.AlfaEditField.ValueChangedFcn = createCallbackFcn(app, @AlfaEditFieldValueChanged, true);
            app.AlfaEditField.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            app.FicheiroPanel = uipanel(app.UIFigure);
            app.FicheiroPanel.Title = 'Ficheiro';
            app.SelecionarFicheiroButton = uibutton(app.FicheiroPanel, 'push');
            app.SelecionarFicheiroButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarFicheiroButtonPushed, true);
            app.SelecionarFicheiroButton.Text = 'Selecionar Ficheiro';
            app.ReiniciarButton = uibutton(app.FicheiroPanel, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.GuardarButton = uibutton(app.FicheiroPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.NomeEditFieldLabel = uilabel(app.FicheiroPanel);
            app.NomeEditFieldLabel.Text = 'Nome';
            app.NomeEditField = uieditfield(app.FicheiroPanel, 'text');
            app.NomeEditField.ValueChangedFcn = createCallbackFcn(app, @NomeEditFieldValueChanged, true);
            app.NomeEditField.Value = 'imagem';
            app.DesfazerButton = uibutton(app.FicheiroPanel, 'push');
            app.DesfazerButton.ButtonPushedFcn = createCallbackFcn(app, @DesfazerButtonPushed, true);
            app.DesfazerButton.Text = 'Desfazer';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end